classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Menu                 matlab.ui.container.Menu
        ImportImageFileMenu  matlab.ui.container.Menu
        GridLayout           matlab.ui.container.GridLayout
        Label                matlab.ui.control.Label
        ReconstructedIntensityDistributionLabel  matlab.ui.control.Label
        HologramLabel        matlab.ui.control.Label
        Image2_2             matlab.ui.control.Image
        AnalysisButton       matlab.ui.control.StateButton
        DistanceSlider       matlab.ui.control.Slider
        DistanceSliderLabel  matlab.ui.control.Label
        Image2               matlab.ui.control.Image
    end

    
    properties (Access = public)
        importFileName
        resultImages
        Finished
    end
    
    methods (Access = private)
        
        function ShowResultImage(app, value)
            result = sprintf("Figs/Reconst_intensity_-%dcm.png", value);
            app.Label.Text = result;
            image = imread(result); 
            app.Image2_2.ImageSource = image;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Finished = 0;
        end

        % Menu selected function: ImportImageFileMenu
        function ImportImageFileMenuSelected(app, event)
            file_name = uigetfile("*.tif");
            image = imread(file_name);
            app.importFileName = file_name;
            app.Image2.ImageSource = image;
            app.Finished = 0;
        end

        % Value changed function: AnalysisButton
        function AnalysisButtonValueChanged(app, event)
            if isempty(app.importFileName)
                uialert(app.UIFigure, 'File are not imported.', 'Warning')
            else
                rmdir("Figs", "s")
                mkdir("Figs")
                DH_simulator(app.importFileName, app.AnalysisButton)
                app.Finished = 1;
            end 
        end

        % Value changed function: DistanceSlider
        function DistanceSliderValueChanged(app, event)
            if app.Finished == 1
                round_value = round(app.DistanceSlider.Value);
                app.DistanceSlider.Value = round_value;
                ShowResultImage(app, round_value);
            else
                app.DistanceSlider.Value = 8;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ImportImageFileMenu = uimenu(app.Menu);
            app.ImportImageFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageFileMenuSelected, true);
            app.ImportImageFileMenu.Text = 'Import Image File';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image2 = uiimage(app.GridLayout);
            app.DistanceSliderLabel = uilabel(app.GridLayout);
            app.DistanceSliderLabel.Text = 'Distance';
            app.DistanceSlider = uislider(app.GridLayout);
            app.DistanceSlider.Limits = [8 18];
            app.DistanceSlider.MajorTicks = [8 9 10 11 12 13 14 15 16 17 18];
            app.DistanceSlider.MajorTickLabels = {'8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.DistanceSlider.ValueChangedFcn = createCallbackFcn(app, @DistanceSliderValueChanged, true);
            app.DistanceSlider.MinorTicks = [];
            app.DistanceSlider.Value = 8;
            app.AnalysisButton = uibutton(app.GridLayout, 'state');
            app.AnalysisButton.ValueChangedFcn = createCallbackFcn(app, @AnalysisButtonValueChanged, true);
            app.AnalysisButton.Text = 'Analysis';
            app.Image2_2 = uiimage(app.GridLayout);
            app.HologramLabel = uilabel(app.GridLayout);
            app.HologramLabel.Text = 'Hologram';
            app.ReconstructedIntensityDistributionLabel = uilabel(app.GridLayout);
            app.ReconstructedIntensityDistributionLabel.Text = {'Reconstructed'; 'Intensity'; 'Distribution'};
            app.Label = uilabel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end